classdef PROJECT_DINOSAUR_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LowerisBetterRadialOnlyLabel    matlab.ui.control.Label
        BePatientLabel                  matlab.ui.control.Label
        RadialOnlyLabel                 matlab.ui.control.Label
        to359LowerisBetterParallelOnlyLabel  matlab.ui.control.Label
        SelectParallelorRadialInputDegreesScanAnalyzeafterLabel  matlab.ui.control.Label
        ReconstructionMethodSwitch      matlab.ui.control.Switch
        ReconstructionMethodSwitchLabel  matlab.ui.control.Label
        ImportImageButton               matlab.ui.control.Button
        AnalyzePlotButton               matlab.ui.control.Button
        Degrees1360EditField            matlab.ui.control.NumericEditField
        Degrees1360EditFieldLabel       matlab.ui.control.Label
        ReconstructButton               matlab.ui.control.Button
        SourceDistanceEditField         matlab.ui.control.NumericEditField
        SourceDistanceEditFieldLabel    matlab.ui.control.Label
        RotationStepSizeEditField       matlab.ui.control.NumericEditField
        RotationStepSizeEditFieldLabel  matlab.ui.control.Label
        NumberofdetectorsEditField      matlab.ui.control.NumericEditField
        NumberofdetectorsEditFieldLabel  matlab.ui.control.Label
        SaveaftergeneratingupdatingLabel  matlab.ui.control.Label
        OffsetY4EditField               matlab.ui.control.NumericEditField
        OffsetY4EditFieldLabel          matlab.ui.control.Label
        SavePhantomButton               matlab.ui.control.Button
        OffsetX4EditField               matlab.ui.control.NumericEditField
        OffsetX4EditFieldLabel          matlab.ui.control.Label
        UpdatePhantomButton             matlab.ui.control.Button
        GeneratePhantomButton           matlab.ui.control.Button
        OffsetY3EditField               matlab.ui.control.NumericEditField
        OffsetY3EditField_2Label        matlab.ui.control.Label
        OffsetX3EditField               matlab.ui.control.NumericEditField
        OffsetX3EditFieldLabel          matlab.ui.control.Label
        IntensitiesEditField            matlab.ui.control.NumericEditField
        IntensitiesEditFieldLabel       matlab.ui.control.Label
        MatrixYPixelsEditField          matlab.ui.control.NumericEditField
        MatrixYPixelsEditFieldLabel     matlab.ui.control.Label
        OffsetY2EditField               matlab.ui.control.NumericEditField
        OffsetY2EditFieldLabel          matlab.ui.control.Label
        MatrixXPixelsEditField          matlab.ui.control.NumericEditField
        MatrixXPixelsEditFieldLabel     matlab.ui.control.Label
        OffsetX2EditField               matlab.ui.control.NumericEditField
        OffsetX2EditFieldLabel          matlab.ui.control.Label
        DimensionHeightEditField        matlab.ui.control.NumericEditField
        DimensionHeightEditFieldLabel   matlab.ui.control.Label
        OffsetY1EditField               matlab.ui.control.NumericEditField
        OffsetY1EditFieldLabel          matlab.ui.control.Label
        DimensionWidthEditField         matlab.ui.control.NumericEditField
        DimensionWidthEditFieldLabel    matlab.ui.control.Label
        OffsetX1EditField               matlab.ui.control.NumericEditField
        OffsetX1EditFieldLabel          matlab.ui.control.Label
        SelectPhantomDropDown           matlab.ui.control.DropDown
        SelectPhantomDropDownLabel      matlab.ui.control.Label
        PhantomsLabel                   matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectPhantomDropDown
        function SelectPhantomDropDownValueChanged(app, event)
            %Test
        end

        % Button pushed function: GeneratePhantomButton
        function GeneratePhantomButtonPushed(app, event)
            cla(app.UIAxes3);
            axis(app.UIAxes3, 'equal');
            hold(app.UIAxes3, 'on');

            % Retrieve values from edit fields
            %dimensionWidth = app.DimensionWidthEditField.Value;
            %dimensionHeight = app.DimensionHeightEditField.Value;
            %matrixXPixels = app.MatrixXPixelsEditField.Value;
            %matrixYPixels = app.MatrixYPixelsEditField.Value;
            %intensities = app.IntensitiesEditField.Value;
            
            switch app.SelectPhantomDropDown.Value
        
                case "Phantom 1"
                    frame_size = [100 100];
                    frame_color = [0.6 0.7 0.8];
        
                    % Center frame
                    frame_pos = [10 10 frame_size];  
                    rectangle(app.UIAxes3 ,'Position', frame_pos, 'FaceColor', frame_color);
        
                    % Outer circle parameters
                    outer_radius = 40; 
                    outer_center = [60 60];
                    
                    viscircles(app.UIAxes3, outer_center, outer_radius, 'EdgeColor','k','LineWidth',5);

                    % Inner circles
                    inner_radius = [12 7 8 2];
                    inner_centers = {[60 60], [37 55], [60 35], [60 90]}; 
                    inner_color = {[0.5 0.5 0.5], [0 0 0], [0 0 0], [0 0 0]}; 

                    inner_color{1} = [0.5 0.5 0.5];

                    for i = 1:length(inner_radius)
                        
                        % Outlined circle
                        viscircles(app.UIAxes3, inner_centers{i}, inner_radius(i), 'EdgeColor','k', 'LineWidth', 5);
                        
                        % Filled circle 
                        if i == 1
                            fill(app.UIAxes3, inner_centers{i}(1) + inner_radius(i)*cos(linspace(0,2*pi,100)), inner_centers{i}(2) + inner_radius(i)*sin(linspace(0,2*pi,100)), inner_color{i}); 
                        else
                            fill(app.UIAxes3, inner_centers{i}, inner_radius(i), inner_color{i}); 
                        end
                    end                    
                case "Phantom 2"    
                    frame_size = [100 100];
                    frame_color = [0.6 0.7 0.8];
                    
                    rectangle(app.UIAxes3 ,'Position',[10 10 frame_size], 'FaceColor', frame_color);  
                    viscircles(app.UIAxes3,[60 60],30,'EdgeColor','k','LineWidth',5);
                    
                    rectangle(app.UIAxes3,'Position',[45 50 30 20], 'FaceColor',[0.5 0.5 0.5]);  
                case "Shepp-Logan"
                    % Generate Shepp-Logan phantom
                    P = phantom(256);
                    %colormap(app.UIAxes3, gray);
                    imagesc(app.UIAxes3, P);
                    
            end
        end

        % Callback function
        function TestButtonPushed(app, event)
            cla(app.UIAxes4);
    
            app.UIAxes4.XLim = [0 100];
            app.UIAxes4.YLim = [0 100];   
        
            rectangle(app.UIAxes4 ,'Position',[10 10 80 80], 'FaceColor',[0.8 0.8 1]) 
            fill(app.UIAxes4, [50 50], 30, [0.8 0.8 1]); 
            viscircles(app.UIAxes4,[50 50],30,'EdgeColor','k');
        end

        % Button pushed function: SavePhantomButton
        function SavePhantomButtonPushed(app, event)
            fig = figure;
            
            % Disable the close button
            set(fig, 'CloseRequestFcn', '');
                      
            % Create a new axes in the new figure
            newAxes = axes(fig);
            
            % Copy the children of UIAxes3 to the new axes
            copyobj(get(app.UIAxes3, 'children'), newAxes);
            
            % Set properties of the new figure and axes as needed
            % For example, you can set the aspect ratio:
            axis(newAxes, 'equal');
            
            % Turn off the axes
            set(newAxes, 'Visible', 'off');
            
            % Set a higher resolution (DPI)
            resolution = 1000; % Change this to the desired resolution
            
            % Automatically save the figure to a fixed file path and name with higher resolution
            print(fig, 'phantom_image_high_res.png', '-dpng', ['-r', num2str(resolution)]);
            
            % Optionally, save a lower resolution version with the original filename
            % saveas(fig, 'phantom_image.png', 'png');
            
            % Enable the close button
            set(fig, 'CloseRequestFcn', 'closereq')
            
            % Close the temporary figure
            close(fig);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            % Allow the user to select a PNG file
            [filename, filepath] = uigetfile('*.png', 'Select a PNG file');
            
            % Check if the user selected a file
            if filename ~= 0
                % Read the image
                image_data = imread(fullfile(filepath, filename));
                
                % Display the image in UIAxes with initial magnification set to 'fit'
                imshow(image_data, 'Parent', app.UIAxes, 'InitialMagnification', 'fit');
                
                % Optionally, set aspect ratio or other properties as needed
                axis(app.UIAxes, 'equal');
                title(app.UIAxes, 'Imported Image');
            end
        end

        % Value changed function: IntensitiesEditField
        function IntensitiesEditFieldValueChanged(app, event)
            % Update the phantom when the intensities change
            value = app.IntensitiesEditField.Value;
            if value < 0
                app.IntensitiesEditField.Value = 0;
            elseif value > 1
                app.IntensitiesEditField.Value = 1;
            end
        end

        % Value changed function: MatrixYPixelsEditField
        function MatrixYPixelsEditFieldValueChanged(app, event)
            % Update the phantom when the intensities change
            %app.GeneratePhantomButtonPushed();
        end

        % Value changed function: MatrixXPixelsEditField
        function MatrixXPixelsEditFieldValueChanged(app, event)
            % Update the phantom when the intensities change
            %app.GeneratePhantomButtonPushed();
        end

        % Value changed function: DimensionHeightEditField
        function DimensionHeightEditFieldValueChanged(app, event)
            % Update the phantom when the intensities change
            %app.GeneratePhantomButtonPushed();
        end

        % Value changed function: DimensionWidthEditField
        function DimensionWidthEditFieldValueChanged(app, event)
            % Update the phantom when the intensities change
            %app.GeneratePhantomButtonPushed();
        end

        % Button pushed function: UpdatePhantomButton
        function UpdatePhantomButtonPushed(app, event)
          % Can NOT use colormap because shapes such as rectangle() have their own color properties...
            % Retrieve values from edit fields

            cla(app.UIAxes3);
            axis(app.UIAxes3, 'equal');
            hold(app.UIAxes3, 'on');
            
            % Retrieve values from edit fields
            dimensionWidth = app.DimensionWidthEditField.Value;
            dimensionHeight = app.DimensionHeightEditField.Value;
            matrixXPixels = app.MatrixXPixelsEditField.Value;
            matrixYPixels = app.MatrixYPixelsEditField.Value;
            intensities = app.IntensitiesEditField.Value;

            % Retrieve values from new edit fields
            offsetX1 = app.OffsetX1EditField.Value;
            offsetY1 = app.OffsetY1EditField.Value;
            offsetX2 = app.OffsetX2EditField.Value;
            offsetY2 = app.OffsetY2EditField.Value;
            offsetX3 = app.OffsetX3EditField.Value;
            offsetY3 = app.OffsetY3EditField.Value;
            offsetX4 = app.OffsetX4EditField.Value;
            offsetY4 = app.OffsetY4EditField.Value;
            
            switch app.SelectPhantomDropDown.Value
            
            case "Phantom 1"
                frame_size = [matrixXPixels matrixYPixels];
                frame_color = [intensities intensities intensities];
                % Center frame
                frame_pos = [10 10 frame_size];  
            
                % Check if frame_size values are greater than or equal to 0
                if frame_size(1) >= 0 && frame_size(2) >= 0
                    rectangle(app.UIAxes3 ,'Position', frame_pos, 'FaceColor', frame_color);
                else
                    disp('Error: frame_size values must be greater than or equal to 0');
                end
            
                % Outer circle parameters
                outer_radius = min(frame_size)/2; 
                outer_center = [frame_pos(1) + frame_size(1)/2, frame_pos(2) + frame_size(2)/2];
                
                % Check if outer_radius is nonnegative
                if outer_radius >= 0
                    viscircles(app.UIAxes3, outer_center, outer_radius, 'EdgeColor','k','LineWidth',5);
                else
                    disp('Error: outer_radius must be nonnegative');
                end
            
                % Inner ellipses
                inner_radii = {[dimensionWidth/2, dimensionHeight/2], [dimensionWidth/3, dimensionHeight/3], [dimensionWidth/4, dimensionHeight/4], [dimensionWidth/5, dimensionHeight/5]}; % User can control the size
                inner_centers = {[outer_center + [offsetX1, offsetY1]], [outer_center + [offsetX2, offsetY2]], [outer_center + [offsetX3, offsetY3]], [outer_center + [offsetX4, offsetY4]]}; 
            
                intensity1 = min(max(intensities + 0.2, 0), 1);
                intensity2 = min(max(intensities + 0.1, 0), 1);
                intensity3 = min(max(intensities, 0), 1);
                intensity4 = min(max(intensities - 0.1, 0), 1);
                inner_color = {[intensity1 intensity1 intensity1], [intensity2 intensity2 intensity2], [intensity3 intensity3 intensity3], [intensity4 intensity4 intensity4]}; 
            
                for i = 1:length(inner_radii)
                    % Ensure the inner ellipse remains within the outer circle
                    distance_to_outer_center = sqrt((inner_centers{i}(1) - outer_center(1))^2 + (inner_centers{i}(2) - outer_center(2))^2);
                    if distance_to_outer_center + max(inner_radii{i}) > outer_radius
                        % Adjust the offsets to bring the inner ellipse within the outer circle
                        scale_factor = (outer_radius - max(inner_radii{i})) / distance_to_outer_center;
                        inner_centers{i} = outer_center + scale_factor * (inner_centers{i} - outer_center);
                    end
            
                    % Check if inner_radii(i) is nonnegative
                    if all(inner_radii{i} >= 0)
                        % Outlined ellipse
                        t = linspace(0, 2*pi, 100);
                        x = inner_centers{i}(1) + inner_radii{i}(1)*cos(t);
                        y = inner_centers{i}(2) + inner_radii{i}(2)*sin(t);
                        plot(app.UIAxes3, x, y, 'Color','k', 'LineWidth', 5);
                        
                        % Filled ellipse 
                        fill(app.UIAxes3, inner_centers{i}(1) + inner_radii{i}(1)*cos(linspace(0,2*pi,100)), inner_centers{i}(2) + inner_radii{i}(2)*sin(linspace(0,2*pi,100)), inner_color{i});
                    else
                        disp('Error: inner_radii must be nonnegative');
                    end
                end
                case "Phantom 2"    % To recreate problem with inner rectangle not showing. Modify the values of phantom 1 first then change to phantom 2 and press update button again.
                    frame_size = [matrixXPixels matrixYPixels];
                    frame_color = [intensities intensities intensities];
                    % Define rectangle_width and rectangle_height before the switch statement
                    rectangle_width = dimensionWidth/2;
                    rectangle_height = dimensionHeight/2;
                                        
                    % Center frame
                    frame_pos = [10 10 frame_size];  
                    rectangle_center = [frame_pos(1) + offsetX1, frame_pos(2) + offsetY1];

                    % Check if rectangle_width and rectangle_height are greater than or equal to 0
                    if rectangle_width >= 0 && rectangle_height >= 0
                        rectangle(app.UIAxes3 ,'Position', frame_pos, 'FaceColor', frame_color);
                    else
                        disp('Error: rectangle_width and rectangle_height must be greater than or equal to 0');
                    end
                    rectangle(app.UIAxes3 ,'Position', frame_pos, 'FaceColor', frame_color);
                    
                    % Ensure the circle remains within the frame
                    circle_radius = min(frame_size)/3;
                    if circle_radius < min(frame_size)/2
                        viscircles(app.UIAxes3,[frame_pos(1) + frame_size(1)/2, frame_pos(2) + frame_size(2)/2],circle_radius,'EdgeColor','k','LineWidth',5);
                    end
                    
                    % Ensure the rectangle remains within the frame
                    %if rectangle_width >= 0 && rectangle_height >= 0 && rectangle_width < frame_size(1) && rectangle_height < frame_size(2) && rectangle_center(1) - rectangle_width/2 >= frame_pos(1) && rectangle_center(1) + rectangle_width/2 <= frame_pos(1) + frame_size(1) && rectangle_center(2) - rectangle_height/2 >= frame_pos(2) && rectangle_center(2) + rectangle_height/2 <= frame_pos(2) + frame_size(2)
                    % Set the grayscale intensity of the rectangle
                    rectangle_intensity = min(max(intensities + 0.4, 0), 1);
                    
                    % Draw the filled rectangle with the mapped intensity
                    rectangle(app.UIAxes3,'Position',[rectangle_center(1) - rectangle_width/2, rectangle_center(2) - rectangle_height/2, rectangle_width, rectangle_height], 'FaceColor',[rectangle_intensity rectangle_intensity rectangle_intensity]);
                    
                    % Draw the black frame
                    rectangle(app.UIAxes3,'Position',[rectangle_center(1) - rectangle_width/2, rectangle_center(2) - rectangle_height/2, rectangle_width, rectangle_height], 'EdgeColor','k', 'LineWidth',1);
                    %end
                case "Shepp-Logan"
                    % Create a default phantom image
                    P = phantom(matrixXPixels, matrixYPixels);
                    
                    % Modify the phantom image using the variables
                    for i = 1:(matrixXPixels-offsetX1)
                        for j = 1:(matrixYPixels-offsetY1)
                            % Modify the pixel values based on your variables
                            P(i+offsetX1,j+offsetY1) = P(i+offsetX1,j+offsetY1) * intensities;
                        end
                    end
                    
                    % Display the modified phantom image
                    imagesc(app.UIAxes3, P);
                    colormap(app.UIAxes3, 'gray'); % for grayscale only,
                    %but doesn't seem to change intensities except for
                    %white and black (0,1)
            end
        end

        % Callback function
        function ImportforGrayscaleButtonPushed(app, event)
            % Open file dialog to select a PNG file
            [file, path] = uigetfile('*.png');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % Read the image
                img = imread(fullfile(path, file));
        
                % Convert the image to grayscale
                gray_img = rgb2gray(img);
        
                % Convert the grayscale image back to RGB
                rgb_img = repmat(gray_img, [1 1 3]);
        
                % Display the grayscale image in the Image UI component
                app.Image.ImageSource = rgb_img;
            end
        end

        % Value changed function: ReconstructionMethodSwitch
        function ReconstructionMethodSwitchValueChanged(app, event)

        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            if app.ReconstructionMethodSwitch.Value == "Parallel"

                if app.SelectPhantomDropDown.Value == "Shepp-Logan"

                    P = phantom(1000);

                else

                    P = imread('phantom_image_high_res.png');
                    P = rgb2gray(P);
                end
                
                % PROJECTION PARALLEL/LINEAR
                thetaX = 0:app.RotationStepSizeEditField.Value:app.Degrees1360EditField.Value;
                [R,xp] = radon(P, thetaX);
                %num_angles_R = size(R, 2); % Example display only, shows the degrees of scan for Parallel, unused
                
                % PROJECTION DATA PARALLEL
                imagesc(app.UIAxes4, thetaX, xp, R);

                % RECONSTRUCTION IMAGE PARALLEL/LINEAR IMAGE
                output_size = max(size(P));
                dthetaX = thetaX(2) - thetaX(1);
                I1 = iradon(R, dthetaX, output_size);
                imshow(I1, 'parent', app.UIAxes);
                
            elseif app.ReconstructionMethodSwitch.Value == "Radial"

                if app.SelectPhantomDropDown.Value == "Shepp-Logan"

                    P = phantom(1000);

                else

                    P = imread('phantom_image_high_res.png');
                    P = rgb2gray(P);
                end
                
                % FAN BEAM / RADIAL 
                D = app.NumberofdetectorsEditField.Value; 
                dsensor = app.SourceDistanceEditField.Value;
                [F1,sensor_pos,fan_rot_angles] = fanbeam(P, D, 'FanSensorSpacing', dsensor);
                
                % PROJECTION DATA FAN BEAM / RADIAL
                imagesc(app.UIAxes4, fan_rot_angles, sensor_pos, F1);

                % FAN BEAN / RADIAL RECONSTRUCTION IMAGE
                output_size = max(size(P));
                Ifan = ifanbeam(F1, D, 'FanSensorSpacing', dsensor, 'OutputSize',output_size);
                imshow(Ifan, 'parent', app.UIAxes);

             end
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Button pushed function: AnalyzePlotButton
        function AnalyzePlotButtonPushed(app, event)

            P = imread('phantom_image_high_res.png');
            P = rgb2gray(P);
            
            % BASE PLOTTING FOR A PARTICULAR THETA ANGLE
            theta = app.Degrees1360EditField.Value;
            [R, xp] = radon(P, theta);
            
            % RADON PLOT AT N DEGREES AS PER THE THETA
            plot(app.UIAxes2, xp, R(:, 1));
        end

        % Value changed function: Degrees1360EditField
        function Degrees1360EditFieldValueChanged(app, event)
            
        end

        % Value changed function: RotationStepSizeEditField
        function RotationStepSizeEditFieldValueChanged(app, event)
            %value = app.RotationStepSizeEditField.Value;
            
        end

        % Value changed function: NumberofdetectorsEditField
        function NumberofdetectorsEditFieldValueChanged(app, event)
           % value = app.NumberofdetectorsEditField.Value;
            
        end

        % Value changed function: SourceDistanceEditField
        function SourceDistanceEditFieldValueChanged(app, event)
            value = app.SourceDistanceEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reconstructed Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity Profile')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Phantom')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Projection Data')
            xlabel(app.UIAxes4, 'Degrees')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [60 120 180 240 300 360];
            app.UIAxes4.XTickLabel = {'60'; '120'; '180'; '240'; '300'; '360'};
            app.UIAxes4.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.PhantomsLabel = uilabel(app.UIFigure);
            app.PhantomsLabel.Text = 'Phantom(s):';
            app.SelectPhantomDropDownLabel = uilabel(app.UIFigure);
            app.SelectPhantomDropDownLabel.Text = 'Select Phantom:';
            app.SelectPhantomDropDown = uidropdown(app.UIFigure);
            app.SelectPhantomDropDown.Items = {'Phantom 1', 'Phantom 2', 'Shepp-Logan'};
            app.SelectPhantomDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPhantomDropDownValueChanged, true);
            app.SelectPhantomDropDown.Value = 'Phantom 1';
            app.OffsetX1EditFieldLabel = uilabel(app.UIFigure);
            app.OffsetX1EditFieldLabel.Text = 'Offset X 1';
            app.OffsetX1EditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionWidthEditFieldLabel = uilabel(app.UIFigure);
            app.DimensionWidthEditFieldLabel.Text = 'Dimension Width:';
            app.DimensionWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionWidthEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionWidthEditFieldValueChanged, true);
            app.OffsetY1EditFieldLabel = uilabel(app.UIFigure);
            app.OffsetY1EditFieldLabel.Text = 'Offset Y 1';
            app.OffsetY1EditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionHeightEditFieldLabel = uilabel(app.UIFigure);
            app.DimensionHeightEditFieldLabel.Text = 'Dimension Height:';
            app.DimensionHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionHeightEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionHeightEditFieldValueChanged, true);
            app.OffsetX2EditFieldLabel = uilabel(app.UIFigure);
            app.OffsetX2EditFieldLabel.Text = 'Offset X 2';
            app.OffsetX2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MatrixXPixelsEditFieldLabel = uilabel(app.UIFigure);
            app.MatrixXPixelsEditFieldLabel.Text = 'Matrix X Pixels: ';
            app.MatrixXPixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MatrixXPixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixXPixelsEditFieldValueChanged, true);
            app.OffsetY2EditFieldLabel = uilabel(app.UIFigure);
            app.OffsetY2EditFieldLabel.Text = 'Offset Y 2';
            app.OffsetY2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MatrixYPixelsEditFieldLabel = uilabel(app.UIFigure);
            app.MatrixYPixelsEditFieldLabel.Text = 'Matrix Y Pixels:';
            app.MatrixYPixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MatrixYPixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixYPixelsEditFieldValueChanged, true);
            app.IntensitiesEditFieldLabel = uilabel(app.UIFigure);
            app.IntensitiesEditFieldLabel.Text = 'Intensities:';
            app.IntensitiesEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensitiesEditField.ValueChangedFcn = createCallbackFcn(app, @IntensitiesEditFieldValueChanged, true);
            app.OffsetX3EditFieldLabel = uilabel(app.UIFigure);
            app.OffsetX3EditFieldLabel.Text = 'Offset X 3';
            app.OffsetX3EditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetY3EditField_2Label = uilabel(app.UIFigure);
            app.OffsetY3EditField_2Label.Text = 'Offset Y 3';
            app.OffsetY3EditField = uieditfield(app.UIFigure, 'numeric');
            app.GeneratePhantomButton = uibutton(app.UIFigure, 'push');
            app.GeneratePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePhantomButtonPushed, true);
            app.GeneratePhantomButton.Text = 'Generate Phantom:';
            app.UpdatePhantomButton = uibutton(app.UIFigure, 'push');
            app.UpdatePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePhantomButtonPushed, true);
            app.UpdatePhantomButton.Text = 'Update Phantom:';
            app.OffsetX4EditFieldLabel = uilabel(app.UIFigure);
            app.OffsetX4EditFieldLabel.Text = 'Offset X 4';
            app.OffsetX4EditField = uieditfield(app.UIFigure, 'numeric');
            app.SavePhantomButton = uibutton(app.UIFigure, 'push');
            app.SavePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhantomButtonPushed, true);
            app.SavePhantomButton.Text = 'Save Phantom';
            app.OffsetY4EditFieldLabel = uilabel(app.UIFigure);
            app.OffsetY4EditFieldLabel.Text = 'Offset Y 4';
            app.OffsetY4EditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveaftergeneratingupdatingLabel = uilabel(app.UIFigure);
            app.SaveaftergeneratingupdatingLabel.Text = 'Save after generating/updating!!!';
            app.NumberofdetectorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofdetectorsEditFieldLabel.Text = 'Number of detectors:';
            app.NumberofdetectorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofdetectorsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofdetectorsEditFieldValueChanged, true);
            app.RotationStepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.RotationStepSizeEditFieldLabel.Text = 'Rotation Step Size:';
            app.RotationStepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @RotationStepSizeEditFieldValueChanged, true);
            app.SourceDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.SourceDistanceEditFieldLabel.Text = 'Source Distance:';
            app.SourceDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SourceDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @SourceDistanceEditFieldValueChanged, true);
            app.ReconstructButton = uibutton(app.UIFigure, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.Degrees1360EditFieldLabel = uilabel(app.UIFigure);
            app.Degrees1360EditFieldLabel.Text = 'Degrees (1-360)';
            app.Degrees1360EditField = uieditfield(app.UIFigure, 'numeric');
            app.Degrees1360EditField.ValueChangedFcn = createCallbackFcn(app, @Degrees1360EditFieldValueChanged, true);
            app.AnalyzePlotButton = uibutton(app.UIFigure, 'push');
            app.AnalyzePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzePlotButtonPushed, true);
            app.AnalyzePlotButton.Text = 'Analyze Plot';
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.ReconstructionMethodSwitchLabel = uilabel(app.UIFigure);
            app.ReconstructionMethodSwitchLabel.Text = 'Reconstruction Method';
            app.ReconstructionMethodSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReconstructionMethodSwitch.Items = {'Parallel', 'Radial'};
            app.ReconstructionMethodSwitch.ValueChangedFcn = createCallbackFcn(app, @ReconstructionMethodSwitchValueChanged, true);
            app.ReconstructionMethodSwitch.Value = 'Parallel';
            app.SelectParallelorRadialInputDegreesScanAnalyzeafterLabel = uilabel(app.UIFigure);
            app.SelectParallelorRadialInputDegreesScanAnalyzeafterLabel.Text = {'Select Parallel or Radial '; 'Input Degrees'; 'Scan & Analyze after'};
            app.to359LowerisBetterParallelOnlyLabel = uilabel(app.UIFigure);
            app.to359LowerisBetterParallelOnlyLabel.Text = '"1 to 359" Lower is Better, Parallel Only';
            app.RadialOnlyLabel = uilabel(app.UIFigure);
            app.RadialOnlyLabel.Text = 'Radial Only';
            app.BePatientLabel = uilabel(app.UIFigure);
            app.BePatientLabel.Text = '(Be Patient)';
            app.LowerisBetterRadialOnlyLabel = uilabel(app.UIFigure);
            app.LowerisBetterRadialOnlyLabel.Text = 'Lower is Better, Radial Only';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PROJECT_DINOSAUR_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end